classdef Final_App_Large < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        HazardInputPanel                matlab.ui.container.Panel
        OrderofFittingPolynomialSwitchLabel  matlab.ui.control.Label
        OrderofFittingPolynomialSwitch  matlab.ui.control.RockerSwitch
        FileUploadTextAreaLabel         matlab.ui.control.Label
        FileUploadTextArea              matlab.ui.control.TextArea
        LoadHazardCurveButton           matlab.ui.control.Button
        LowerHazardRange                matlab.ui.control.TextArea
        UpperHazardRange                matlab.ui.control.TextArea
        LowerIntegrationRange           matlab.ui.control.TextArea
        UpperIntegrationRange           matlab.ui.control.TextArea
        toLabel_2                       matlab.ui.control.Label
        toLabel_3                       matlab.ui.control.Label
        HazardFitRangeLabel             matlab.ui.control.Label
        SaIntegrationRangeLabel         matlab.ui.control.Label
        StructureInputPanel             matlab.ui.container.Panel
        LoadStructureDataButton         matlab.ui.control.Button
        FileUploadTextArea_2Label       matlab.ui.control.Label
        FileUploadTextArea_2            matlab.ui.control.TextArea
        LoadStructureResponseButton     matlab.ui.control.Button
        FileUploadTextArea_3Label       matlab.ui.control.Label
        FileUploadTextArea_3            matlab.ui.control.TextArea
        FragilitiesandLossPanel         matlab.ui.container.Panel
        FileUploadTextArea_4Label       matlab.ui.control.Label
        FileUploadTextArea_4            matlab.ui.control.TextArea
        LoadFragilityLossDataButton     matlab.ui.control.Button
        DemolitionParametersPanel       matlab.ui.container.Panel
        MedianRIDRTextAreaLabel         matlab.ui.control.Label
        MedianRIDR                      matlab.ui.control.TextArea
        DispersionRIDRTextAreaLabel     matlab.ui.control.Label
        DispersionRIDR                  matlab.ui.control.TextArea
        RunAnalysisButton               matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        HazardTab                       matlab.ui.container.Tab
        FragilityDisplay_4              matlab.ui.container.Panel
        polyOrder                       matlab.ui.control.Label
        FragilityDisplay_5              matlab.ui.container.Panel
        fittingRange                    matlab.ui.control.Label
        FragilityDisplay_6              matlab.ui.container.Panel
        integrationRange                matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        FragilityDisplay_8              matlab.ui.container.Panel
        SaMCE                           matlab.ui.control.Label
        FragilityDisplay_9              matlab.ui.container.Panel
        SaDBE                           matlab.ui.control.Label
        StructuralResponseTab           matlab.ui.container.Tab
        EDPPlotOptionDropDownLabel      matlab.ui.control.Label
        EDPPlotOptionDropDown           matlab.ui.control.DropDown
        UIAxes5                         matlab.ui.control.UIAxes
        PlotLabel                       matlab.ui.control.Label
        PlotDropDown                    matlab.ui.control.DropDown
        CollapseFragilityTab            matlab.ui.container.Tab
        FragilityDisplay                matlab.ui.container.Panel
        CFragilityMean                  matlab.ui.control.Label
        FragilityDisplay_2              matlab.ui.container.Panel
        CFragilityDispersion            matlab.ui.control.Label
        FragilityDisplay_3              matlab.ui.container.Panel
        CFragilityPC50                  matlab.ui.control.Label
        FragilityDisplay_7              matlab.ui.container.Panel
        MAF50                           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        CollapsePlotOptionLabel         matlab.ui.control.Label
        CollapsePlotOptions             matlab.ui.control.DropDown
        DamageFragilitiesTab            matlab.ui.container.Tab
        ComponentDropDownLabel          matlab.ui.control.Label
        ComponentDropDown               matlab.ui.control.DropDown
        UIAxes3                         matlab.ui.control.UIAxes
        DemolitionTab                   matlab.ui.container.Tab
        UIAxes6                         matlab.ui.control.UIAxes
        LossEstimationTab               matlab.ui.container.Tab
        AverageAnnualLossPanel          matlab.ui.container.Panel
        AAL                             matlab.ui.control.Label
        LossDeaggregationPanel          matlab.ui.container.Panel
        DNELabel_4                      matlab.ui.control.Label
        DNELabel_5                      matlab.ui.control.Label
        DNELabel_6                      matlab.ui.control.Label
        CollapseDeaggregation           matlab.ui.control.Label
        DemoAggregation                 matlab.ui.control.Label
        RepairAggregation               matlab.ui.control.Label
        PercentTotalCostLossPanel       matlab.ui.container.Panel
        PercentCost                     matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        LossPlotOptionDropDownLabel     matlab.ui.control.Label
        LossPlotOptionsDropDown         matlab.ui.control.DropDown
    end


  properties (Access = public)
        handles % Structure with many things in it
        hazardFile % Filename of the hazard curve
    end

    methods (Access = public)
    
        function getPolynomialOrder(app)
            order = app.OrderofFittingPolynomialSwitch.Value;
            if order == '3rd'
                app.handles.PolynomialOrder = 3;
            else
                app.handles.PolynomialOrder = 4;
            end
        end
        
        function plotPolyFit(app)
            cla(app.UIAxes2, 'reset')
            loglog(app.UIAxes2, app.handles.hazard(:,1), app.handles.hazard(:,2), 'o') 
            hold(app.UIAxes2, 'on')
            t=text(app.UIAxes2, app.handles.hazard(1,1), min(app.handles.hazard(:,2)), app.handles.str1);
            t.FontSize = 9;
            t.BackgroundColor = 'w';
            t.EdgeColor = 'k';
            loglog(app.UIAxes2,app.handles.hazardCurve(1,:), app.handles.hazardCurve(2,:))
            title(app.UIAxes2,'Seismic Hazard Curve')
            xlabel(app.UIAxes2,'Sa (g)')
            ylabel(app.UIAxes2,'Mean Annual Frequency (1/yr)')
            legend(app.UIAxes2,'Hazard Curve','Polyfit')
            set(app.UIAxes2, ...
              'Box'         , 'off'     , ...
              'TickDir'     , 'out'     , ...
              'TickLength'  , [.02 .02] , ...
              'XMinorTick'  , 'on'      , ...
              'YMinorTick'  , 'on');
            grid(app.UIAxes2, 'on')
            hold(app.UIAxes2, 'off')
            
        end
        
        function plotEDPresponse(app, value, value2)
            if strcmp(value2, 'Median EDP v. IM')
                cla(app.UIAxes5, 'reset')
                plot(app.UIAxes5,app.handles.stripes,app.handles.EDPtype.(value).GMData, 'Color', [0.8 0.8 0.8], 'HandleVisibility', 'off')
                hold(app.UIAxes5, 'on');
                plot(app.UIAxes5,app.handles.stripes,app.handles.EDPtype.(value).meanEDP,'LineWidth',3,'Color','r')
                plot(app.UIAxes5,app.handles.stripes,app.handles.EDPtype.(value).medianEDP,'LineWidth',3,'Color','b')
                legend(app.UIAxes5, 'Mean Values', 'Median Values')
                title(app.UIAxes5,[value, ' v. IM'])
                ylabel(app.UIAxes5, value)
                xlabel(app.UIAxes5,'Sa (g)')
                set(app.UIAxes5, ...
                  'Box'         , 'off'     , ...
                  'TickDir'     , 'out'     , ...
                  'TickLength'  , [.02 .02] , ...
                  'XMinorTick'  , 'on'      , ...
                  'YMinorTick'  , 'on');
                hold(app.UIAxes5, 'off');
                grid(app.UIAxes5, 'on');
            elseif strcmp(value2,'Mean Annual Frequency')
                cla(app.UIAxes5, 'reset')
                if contains(value,'RIDR')    
                plot(app.UIAxes5,app.handles.EDP.RIDR,app.handles.EDPtype.(value).maf_edp)
                elseif contains(value,'IDR')
                plot(app.UIAxes5,app.handles.EDP.IDR,app.handles.EDPtype.(value).maf_edp)
                elseif contains(value,'PFA')
                plot(app.UIAxes5,app.handles.EDP.PFA,app.handles.EDPtype.(value).maf_edp)
                end
                hold(app.UIAxes5, 'on');
                title(app.UIAxes5,['Mean Annual Frequency (1/yr)', value])
                ylabel(app.UIAxes5, value)
                xlabel(app.UIAxes5,'EDP')
                set(app.UIAxes5, ...
                  'Box'         , 'off'     , ...
                  'TickDir'     , 'out'     , ...
                  'TickLength'  , [.02 .02] , ...
                  'XMinorTick'  , 'on'      , ...
                  'YMinorTick'  , 'on');
                hold(app.UIAxes5, 'off');
                grid(app.UIAxes5, 'on');
            end
        end

        
        function plotFragilities(app, value)
            cla(app.UIAxes3, 'reset')
            plot(app.UIAxes3, app.handles.(value).EDP, app.handles.(value).P_Damage, 'LineWidth', 2)
            legendary = strings(1, app.handles.(value).NumDS+1);
            legendary(1) = 'No Damage';
            for i = 1:app.handles.(value).NumDS
                legendary(i+1) = strcat('DS', num2str(i));
            end
            title(app.UIAxes3, ([(value), ' Fragilities']))
            xlabel(app.UIAxes3, strcat('EDP (', app.handles.(value).EDPtype, ')'))
            ylabel(app.UIAxes3, 'P[Ds = ds | EDP]')
            legend(app.UIAxes3, legendary)
            set(app.UIAxes3, ...
              'Box'         , 'off'     , ...
              'TickDir'     , 'out'     , ...
              'TickLength'  , [.02 .02] , ...
              'XMinorTick'  , 'on'      , ...
              'YMinorTick'  , 'on');
            grid(app.UIAxes3, 'on')
        end
        
        function plotCollapse(app, n, value)
            cla(app.UIAxes, 'reset')
            if strcmp('Collapse Deaggregation', value)
            plot(app.UIAxes, app.handles.hazardDerivative(1,:), app.handles.CollapseDeaggregation, 'k', 'LineWidth', 1.5)
            xlabel(app.UIAxes,'Sa (g)')
            ylabel(app.UIAxes,'P[C | IM] * HazardDerivative')
            else
            plot(app.UIAxes,app.handles.stripes,app.handles.numberCollapse/n,'o')
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, app.handles.hazardDerivative(1,:),app.handles.P_collapse,'k', 'LineWidth', 1.5)
            legend(app.UIAxes,'Stripe Analysis Median Collapse', 'Max Likelihood Fragility Fit')
            legend(app.UIAxes, 'Location', 'southeast')
            xlabel(app.UIAxes,'Sa (g)')
            ylabel(app.UIAxes,'P[C | IM]')
            end
            grid(app.UIAxes, 'on')
            
            set(app.UIAxes, ...
              'Box'         , 'off'     , ...
              'TickDir'     , 'out'     , ...
              'TickLength'  , [.02 .02] , ...
              'XMinorTick'  , 'on'      , ...
              'YMinorTick'  , 'on');
            hold(app.UIAxes, 'off')
        end
        
        function plotDemoProb(app)
            cla(app.UIAxes6, 'reset')
            hold(app.UIAxes6, 'on')
            plot(app.UIAxes6, app.handles.hazardCurve(1,:), app.handles.P_NC_demo, '-k', 'LineWidth', 1.5) 
            plot(app.UIAxes6, app.handles.hazardCurve(1,:), app.handles.P_NC_repair, '-r', 'LineWidth', 1.5) 
            plot(app.UIAxes6, app.handles.hazardCurve(1,:), app.handles.P_collapse, '-b', 'LineWidth', 1.5) 
            xlabel(app.UIAxes6,'Sa (g)')
            ylabel(app.UIAxes6,'P(Event)')
            legend(app.UIAxes6,'P(Demo | NC)', 'P(No Demo | NC)', 'P(Collapse)')
            legend(app.UIAxes6, 'Location', 'northeast')
            set(app.UIAxes6, ...
              'Box'         , 'off'     , ...
              'TickDir'     , 'out'     , ...
              'TickLength'  , [.02 .02] , ...
              'XMinorTick'  , 'on'      , ...
              'YMinorTick'  , 'on');
            title(app.UIAxes6, 'Probability of Demo, Collapse, and Repair')
            grid(app.UIAxes6, 'on')
            hold(app.UIAxes6, 'off')
        end
           
        function plotLossDeaggregation(app, value)
            cla(app.UIAxes4, 'reset')
            if strcmp('% Loss Comparison', value) == 1
                bar(app.UIAxes4, [app.handles.AAL_collapse, app.handles.AAL_demo, app.handles.AAL_repair; 1, 1, 1], 0.4, 'stacked')
                set(app.UIAxes4, 'xlim', [0.5, 1.5])
                set(app.UIAxes4,'xtick',[])
                set(app.UIAxes4, 'YGrid', 'on')
                ylabel(app.UIAxes4, 'Average Annual Loss ($)')
                % Add a legend
                legend(app.UIAxes4, 'Collapse', 'Demo', 'Repair')
            else
            hold(app.UIAxes4, 'on')
            if strcmp('Loss Deaggregation', value) == 1
            plot(app.UIAxes4, app.handles.hazardCurve(1,:), app.handles.deagplot, 'k', 'LineWidth', 1.5)
            plot(app.UIAxes4, app.handles.hazardCurve(1,:), app.handles.deagplotR, '--r')
            plot(app.UIAxes4, app.handles.hazardCurve(1,:), app.handles.deagplotC, '--g')
            plot(app.UIAxes4, app.handles.hazardCurve(1,:), app.handles.deagplotD, '--b')
            xlabel(app.UIAxes4,'Sa (g)')
            ylabel(app.UIAxes4,'E[L | IM} * dervativeHazard')
            else
            plot(app.UIAxes4, app.handles.hazardCurve(1,:), app.handles.L_IM, 'k', 'LineWidth', 1.5)
            plot(app.UIAxes4, app.handles.hazardCurve(1,:), app.handles.ncLoss_IM, '--r')
            plot(app.UIAxes4, app.handles.hazardCurve(1,:), app.handles.collapseLoss_IM, '--g')
            plot(app.UIAxes4, app.handles.hazardCurve(1,:), app.handles.demoLoss_IM, '--b')
            xlabel(app.UIAxes4,'Sa (g)')
            ylabel(app.UIAxes4,'E[L|IM] ($)')
            end
            legend(app.UIAxes4,'Total Loss', 'No Collapse, Repair', 'Collapse', 'No Collapse, Demo')
            legend(app.UIAxes4, 'Location', 'southeast')
            set(app.UIAxes4, ...
              'Box'         , 'off'     , ...
              'TickDir'     , 'out'     , ...
              'TickLength'  , [.02 .02] , ...
              'XMinorTick'  , 'on'      , ...
              'YMinorTick'  , 'on');
            grid(app.UIAxes4, 'on')
            hold(app.UIAxes4, 'off')
            end
        end
        
        function stripes = parseStripes(app, filenames)
            stripes = zeros(length(filenames), 1);
            for i = 1:length(filenames)
                str = filenames{i};
                split = strsplit(str, {'_Sa', 'Sa', 'Sa_', '_'});
                for j = 1:length(split)
                    check = str2double(split{j});
                    if isnan(check)
                        continue
                    else
                        stripes(i) = check;
                        break
                    end
                end
            end
        end
end


    methods (Access = private)

        % Button pushed function: LoadStructureDataButton
        function LoadStructure(app, event)
            app.handles.structureFile = uigetfile('*.csv');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
             if isequal(app.handles.structureFile, 0)
                 disp('User select Cancel')
             else
                 app.FileUploadTextArea_2.Value = app.handles.structureFile;
             end             
        end

        % Button pushed function: LoadStructureResponseButton
        function LoadResponse(app, event)
             app.handles.responseFiles = uigetfile('*.csv', 'Select all Files', 'MultiSelect', 'on');
             app.UIFigure.Visible = 'off';
             app.UIFigure.Visible = 'on';
             if isequal(app.handles.responseFiles, 0)
                 app.FileUploadTextArea_3.Value = 'User Selection Cancelled';
             else
                 app.FileUploadTextArea_3.Value = app.handles.responseFiles;
             end
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysis(app, event)
            [y, Fs] = audioread('Requiem.mp3');
            player = audioplayer(y, Fs);
            
            dialogue = waitbar(0, 'Hazard Analysis');
            
            % Play music
            play(player)
            
            % Load in the hazard polynomial order and limits
            app.getPolynomialOrder();
            app.handles.hazard = load(app.hazardFile);
            l = str2double(app.LowerHazardRange.Value);
            u = str2double(app.UpperHazardRange.Value);
            app.fittingRange.Text = strcat(app.LowerHazardRange.Value,'g-',app.UpperHazardRange.Value, 'g');
            app.handles.interval = [l, u];
            l = str2double(app.LowerIntegrationRange.Value);
            u = str2double(app.UpperIntegrationRange.Value);
            app.integrationRange.Text = strcat(app.LowerIntegrationRange.Value,'g-',app.UpperIntegrationRange.Value, 'g');
            app.handles.SaIntegration = [l, u];
            
            % Create a polyfit of the curve
            [app]= createPolyFit(app);
            app.plotPolyFit();
            
            % Find the MCE and DBE IM for hazard curve specified
            DBE = interp1(app.handles.hazardCurve(2,:), app.handles.hazardCurve(1,:), .002107);
            MCE = interp1(app.handles.hazardCurve(2,:), app.handles.hazardCurve(1,:), .000404);
            app.SaMCE.Text = strcat(num2str(round(MCE,2)), ' g');
            app.SaDBE.Text = strcat(num2str(round(DBE,2)), ' g');
            
            % Output data to the hazard tab
            app.polyOrder.Text = app.OrderofFittingPolynomialSwitch.Value;
            
            % Initialize range of variables for integration
            app.handles.EDP.IDR = 0.0001:.0001:.15;
            app.handles.EDP.PFA = 0.001:.005:6.0;
            app.handles.EDP.RIDR = 0.0001:.0001:.1;
            
            % GUI Inputs
            waitbar(0.2, dialogue, 'Analyzing Structure Response');
            ns = length(app.handles.responseFiles); %ns - number of stripes
            file = size(readtable(app.handles.responseFiles{1})); % Can we get this from loading file?
            n = file(2)-1; %n - number of GM
            app.handles.n = n;
            
            % Analyze Response
            filenames = app.handles.responseFiles;
            app.handles.stripes = parseStripes(app, filenames);
            app = LoadStripeData(app, filenames, ns);
            app = ResponseEstimation(app);
            
            app.EDPPlotOptionDropDown.Items = app.handles.EDPnames;
            
            % Collapse Fragilities
            waitbar(0.6, dialogue, 'Computing Collapse Fragility');
            app = CollapseFragility(n, app);
            app.CFragilityMean.Text = strcat(num2str(app.handles.CollapseMedian), ' g');
            app.CFragilityDispersion.Text = num2str(app.handles.CollapseStd);
            app.CFragilityPC50.Text = num2str(app.handles.Prob_50);
            app.MAF50.Text = num2str(app.handles.MAF_c);
            plotCollapse(app, n, app.CollapsePlotOptions.Value)
            
            % component fragilities
            waitbar(.8, dialogue, 'Computing Component Fragilities');
            filename = app.handles.fragilitylossFile;
            app = loadComputeDamageFragilities(app, filename);
            app.ComponentDropDown.Items = app.handles.Components;
            
            % Structure Data
            filename = app.handles.structureFile;
            app = loadStructure(filename, app);
            
            %Loss Analysis
            waitbar(.95, dialogue, 'Conducting Loss Analysis');
            % Return expected loss given EDP conditioned on no collapse and no repair
            app = expectedLoss_EDP(app);
            
            % Return expected loss given IM conditioned on no collapse and no repair
            app = expectedLoss_IM(app);
           
            % Probability of Demolition Given No Collapse
            app.handles.demo.RIDR_median = str2double(app.MedianRIDR.Value);
            app.handles.demo.RIDR_dispersion = str2double(app.DispersionRIDR.Value);
            app = ExpectedLoss(app);
            
            % Find probabilities of all 3 ingredients (repair, demo, collapse) and plot
            % all loss functions given IM
            app = repairCollapseDemoProb(app);
            plotDemoProb(app)
            
            % Calculate the AAL and deaggregation ratios (could be used for fun things)
            app = averageAnnualLoss(app);
            plotLossDeaggregation(app, app.LossPlotOptionsDropDown.Value)
           
            AAL_t = num2str(round(app.handles.AAL,0));
            
            % Display loss analysis data
            app.AAL.Text = strcat('$ ', AAL_t);
            app.CollapseDeaggregation.Text = strcat('$', num2str(round(app.handles.AAL_collapse,0)), ' (', num2str(round(app.handles.ratio.Collapse , 3)*100), '%)');
            app.DemoAggregation.Text = strcat('$', num2str(round(app.handles.AAL_demo,0)), ' (', num2str(round(app.handles.ratio.Demo , 3)*100), '%)');
            app.RepairAggregation.Text = strcat('$', num2str(round(app.handles.AAL_repair,0)), ' (', num2str(round(app.handles.ratio.Repair , 3)*100), '%)');
            app.PercentCost.Text = strcat(num2str(round(app.handles.ratio.totalLoss*100, 3)), ' %');
            
            % Close loading bar, finish the analysis
            close(dialogue)
            app.Lamp.Color = 'g';
            pause(25)
            pause(player)
        end

        % Button pushed function: LoadFragilityLossDataButton
        function LoadFragilityLoss(app, event)
             app.handles.fragilitylossFile = uigetfile('*.csv');
             app.UIFigure.Visible = 'off';
             app.UIFigure.Visible = 'on';
             if isequal(app.handles.fragilitylossFile, 0)
                 app.FileUploadTextArea_4.Value = 'User Selected Cancel';
             else
                 app.FileUploadTextArea_4.Value = app.handles.fragilitylossFile;
             end
        end

        % Value changed function: EDPPlotOptionDropDown, PlotDropDown
        function EDPmedianResponse(app, event)
            value = app.EDPPlotOptionDropDown.Value;
            value2 = app.PlotDropDown.Value;
            plotEDPresponse(app,value, value2);
        end

        % Value changed function: ComponentDropDown
        function fragilityPlot(app, event)
            value = app.ComponentDropDown.Value;
            plotFragilities(app,value);
        end

        % Menu selected function: QuitMenu
        function Quit(app, event)
             closereq
        end

        % Button pushed function: LoadHazardCurveButton
        function LoadHazardCurve(app, event)
             app.hazardFile = uigetfile('*.txt', '*.csv');
             app.UIFigure.Visible = 'off';
             app.UIFigure.Visible = 'on';
             if isequal(app.hazardFile, 0)
                 app.FileUploadTextArea.Value = 'User Selected Cancel';
             else
                 app.FileUploadTextArea.Value = app.hazardFile;
             end
        end

        % Value changed function: LossPlotOptionsDropDown
        function LossPlotOptionsDropDownValueChanged(app, event)
            value = app.LossPlotOptionsDropDown.Value;
            plotLossDeaggregation(app, value);
        end

        % Value changed function: CollapsePlotOptions
        function CollapsePlotOptionsValueChanged(app, event)
            value = app.CollapsePlotOptions.Value;
            plotCollapse(app, app.handles.n, value)
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            saveStuff(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @Quit, true);
            app.QuitMenu.Accelerator = 'q';
            app.QuitMenu.Text = 'Quit';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.HazardInputPanel = uipanel(app.InputsTab);
            app.HazardInputPanel.Title = 'Hazard Input';
            app.OrderofFittingPolynomialSwitchLabel = uilabel(app.HazardInputPanel);
            app.OrderofFittingPolynomialSwitchLabel.Text = 'Order of Fitting Polynomial';
            app.OrderofFittingPolynomialSwitch = uiswitch(app.HazardInputPanel, 'rocker');
            app.OrderofFittingPolynomialSwitch.Items = {'3rd', '4th'};
            app.OrderofFittingPolynomialSwitch.Value = '3rd';
            app.FileUploadTextAreaLabel = uilabel(app.HazardInputPanel);
            app.FileUploadTextAreaLabel.Text = 'File Upload:';
            app.FileUploadTextArea = uitextarea(app.HazardInputPanel);
            app.FileUploadTextArea.Editable = 'off';
            app.FileUploadTextArea.Value = {'Please Load Hazard'};
            app.LoadHazardCurveButton = uibutton(app.HazardInputPanel, 'push');
            app.LoadHazardCurveButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHazardCurve, true);
            app.LoadHazardCurveButton.Text = 'Load Hazard Curve';
            app.LowerHazardRange = uitextarea(app.HazardInputPanel);
            app.LowerHazardRange.Value = {'0.01'};
            app.UpperHazardRange = uitextarea(app.HazardInputPanel);
            app.UpperHazardRange.Value = {'2'};
            app.LowerIntegrationRange = uitextarea(app.HazardInputPanel);
            app.LowerIntegrationRange.Value = {'0.01'};
            app.UpperIntegrationRange = uitextarea(app.HazardInputPanel);
            app.UpperIntegrationRange.Value = {'3'};
            app.toLabel_2 = uilabel(app.HazardInputPanel);
            app.toLabel_2.Text = 'to';
            app.toLabel_3 = uilabel(app.HazardInputPanel);
            app.toLabel_3.Text = 'to';
            app.HazardFitRangeLabel = uilabel(app.HazardInputPanel);
            app.HazardFitRangeLabel.Text = 'Hazard Fit Range:';
            app.SaIntegrationRangeLabel = uilabel(app.HazardInputPanel);
            app.SaIntegrationRangeLabel.Text = 'Sa Integration Range:';
            app.StructureInputPanel = uipanel(app.InputsTab);
            app.StructureInputPanel.Title = 'Structure Input';
            app.LoadStructureDataButton = uibutton(app.StructureInputPanel, 'push');
            app.LoadStructureDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStructure, true);
            app.LoadStructureDataButton.Text = 'Load Structure Data';
            app.FileUploadTextArea_2Label = uilabel(app.StructureInputPanel);
            app.FileUploadTextArea_2Label.Text = 'File Upload:';
            app.FileUploadTextArea_2 = uitextarea(app.StructureInputPanel);
            app.FileUploadTextArea_2.Value = {'Please Load Structure'};
            app.LoadStructureResponseButton = uibutton(app.StructureInputPanel, 'push');
            app.LoadStructureResponseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResponse, true);
            app.LoadStructureResponseButton.Text = 'Load Structure Response';
            app.FileUploadTextArea_3Label = uilabel(app.StructureInputPanel);
            app.FileUploadTextArea_3Label.Text = 'File Upload:';
            app.FileUploadTextArea_3 = uitextarea(app.StructureInputPanel);
            app.FileUploadTextArea_3.Value = {'Please Load Response'};
            app.FragilitiesandLossPanel = uipanel(app.InputsTab);
            app.FragilitiesandLossPanel.Title = 'Fragilities and Loss';
            app.FileUploadTextArea_4Label = uilabel(app.FragilitiesandLossPanel);
            app.FileUploadTextArea_4Label.Text = 'File Upload:';
            app.FileUploadTextArea_4 = uitextarea(app.FragilitiesandLossPanel);
            app.FileUploadTextArea_4.Editable = 'off';
            app.FileUploadTextArea_4.Value = {'Please Load Fragilities'};
            app.LoadFragilityLossDataButton = uibutton(app.FragilitiesandLossPanel, 'push');
            app.LoadFragilityLossDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFragilityLoss, true);
            app.LoadFragilityLossDataButton.Text = 'Load Fragility/Loss Data';
            app.DemolitionParametersPanel = uipanel(app.InputsTab);
            app.DemolitionParametersPanel.Title = 'Demolition Parameters:';
            app.MedianRIDRTextAreaLabel = uilabel(app.DemolitionParametersPanel);
            app.MedianRIDRTextAreaLabel.Text = 'Median RIDR:';
            app.MedianRIDR = uitextarea(app.DemolitionParametersPanel);
            app.MedianRIDR.Value = {'0.015'};
            app.DispersionRIDRTextAreaLabel = uilabel(app.DemolitionParametersPanel);
            app.DispersionRIDRTextAreaLabel.Text = 'Dispersion RIDR:';
            app.DispersionRIDR = uitextarea(app.DemolitionParametersPanel);
            app.DispersionRIDR.Value = {'0.3'};
            app.RunAnalysisButton = uibutton(app.InputsTab, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysis, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.Lamp = uilamp(app.InputsTab);
            app.HazardTab = uitab(app.TabGroup);
            app.HazardTab.Title = 'Hazard';
            app.FragilityDisplay_4 = uipanel(app.HazardTab);
            app.FragilityDisplay_4.Title = 'Polynomial Order';
            app.polyOrder = uilabel(app.FragilityDisplay_4);
            app.polyOrder.Text = '-';
            app.FragilityDisplay_5 = uipanel(app.HazardTab);
            app.FragilityDisplay_5.Title = 'Fitting Range';
            app.fittingRange = uilabel(app.FragilityDisplay_5);
            app.fittingRange.Text = '-';
            app.FragilityDisplay_6 = uipanel(app.HazardTab);
            app.FragilityDisplay_6.Title = 'Integration Range';
            app.integrationRange = uilabel(app.FragilityDisplay_6);
            app.integrationRange.Text = '-';
            app.UIAxes2 = uiaxes(app.HazardTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.YLim = [0 1];
            app.UIAxes2.ZLim = [0 1];
            app.UIAxes2.CLim = [0 1];
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2.ZTick = [0 0.5 1];
            app.FragilityDisplay_8 = uipanel(app.HazardTab);
            app.FragilityDisplay_8.Title = '2% in 50yr Sa';
            app.SaMCE = uilabel(app.FragilityDisplay_8);
            app.SaMCE.Text = '-';
            app.FragilityDisplay_9 = uipanel(app.HazardTab);
            app.FragilityDisplay_9.Title = '10% in 50yr Sa';
            app.SaDBE = uilabel(app.FragilityDisplay_9);
            app.SaDBE.Text = '-';
            app.StructuralResponseTab = uitab(app.TabGroup);
            app.StructuralResponseTab.Title = 'Structural Response';
            app.EDPPlotOptionDropDownLabel = uilabel(app.StructuralResponseTab);
            app.EDPPlotOptionDropDownLabel.Text = 'EDP Plot Option:';
            app.EDPPlotOptionDropDown = uidropdown(app.StructuralResponseTab);
            app.EDPPlotOptionDropDown.Items = {'None', 'Option 2', 'Option 3', 'Option 4'};
            app.EDPPlotOptionDropDown.ValueChangedFcn = createCallbackFcn(app, @EDPmedianResponse, true);
            app.EDPPlotOptionDropDown.Value = 'None';
            app.UIAxes5 = uiaxes(app.StructuralResponseTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes5.XLim = [0 1];
            app.UIAxes5.YLim = [0 1];
            app.UIAxes5.ZLim = [0 1];
            app.UIAxes5.CLim = [0 1];
            app.UIAxes5.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes5.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes5.ZTick = [0 0.5 1];
            app.PlotLabel = uilabel(app.StructuralResponseTab);
            app.PlotLabel.Text = 'Plot:';
            app.PlotDropDown = uidropdown(app.StructuralResponseTab);
            app.PlotDropDown.Items = {'Median EDP v. IM', 'Mean Annual Frequency'};
            app.PlotDropDown.ValueChangedFcn = createCallbackFcn(app, @EDPmedianResponse, true);
            app.PlotDropDown.Value = 'Median EDP v. IM';
            app.CollapseFragilityTab = uitab(app.TabGroup);
            app.CollapseFragilityTab.Title = 'Collapse Fragility';
            app.FragilityDisplay = uipanel(app.CollapseFragilityTab);
            app.FragilityDisplay.Title = 'Collapse Fragility Median';
            app.CFragilityMean = uilabel(app.FragilityDisplay);
            app.CFragilityMean.Text = '-';
            app.FragilityDisplay_2 = uipanel(app.CollapseFragilityTab);
            app.FragilityDisplay_2.Title = 'Collapse Fragility Dispersion';
            app.CFragilityDispersion = uilabel(app.FragilityDisplay_2);
            app.CFragilityDispersion.Text = '-';
            app.FragilityDisplay_3 = uipanel(app.CollapseFragilityTab);
            app.FragilityDisplay_3.Title = 'P(C) in 50 yr.';
            app.CFragilityPC50 = uilabel(app.FragilityDisplay_3);
            app.CFragilityPC50.Text = '-';
            app.FragilityDisplay_7 = uipanel(app.CollapseFragilityTab);
            app.FragilityDisplay_7.Title = 'MAF Collapse';
            app.MAF50 = uilabel(app.FragilityDisplay_7);
            app.MAF50.Text = '-';
            app.UIAxes = uiaxes(app.CollapseFragilityTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.CollapsePlotOptionLabel = uilabel(app.CollapseFragilityTab);
            app.CollapsePlotOptionLabel.Text = 'Collapse Plot Option:';
            app.CollapsePlotOptions = uidropdown(app.CollapseFragilityTab);
            app.CollapsePlotOptions.Items = {'P[C | IM]', 'Collapse Deaggregation'};
            app.CollapsePlotOptions.ValueChangedFcn = createCallbackFcn(app, @CollapsePlotOptionsValueChanged, true);
            app.CollapsePlotOptions.Value = 'P[C | IM]';
            app.DamageFragilitiesTab = uitab(app.TabGroup);
            app.DamageFragilitiesTab.Title = 'Damage Fragilities';
            app.ComponentDropDownLabel = uilabel(app.DamageFragilitiesTab);
            app.ComponentDropDownLabel.Text = 'Component';
            app.ComponentDropDown = uidropdown(app.DamageFragilitiesTab);
            app.ComponentDropDown.Items = {'None', 'Option 2', 'Option 3', 'Option 4'};
            app.ComponentDropDown.ValueChangedFcn = createCallbackFcn(app, @fragilityPlot, true);
            app.ComponentDropDown.Value = 'None';
            app.UIAxes3 = uiaxes(app.DamageFragilitiesTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3.XLim = [0 1];
            app.UIAxes3.YLim = [0 1];
            app.UIAxes3.ZLim = [0 1];
            app.UIAxes3.CLim = [0 1];
            app.UIAxes3.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes3.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes3.ZTick = [0 0.5 1];
            app.DemolitionTab = uitab(app.TabGroup);
            app.DemolitionTab.Title = 'Demolition';
            app.UIAxes6 = uiaxes(app.DemolitionTab);
            title(app.UIAxes6, 'Probability of Demolition')
            xlabel(app.UIAxes6, 'Sa (g)')
            ylabel(app.UIAxes6, 'P(Event)')
            app.LossEstimationTab = uitab(app.TabGroup);
            app.LossEstimationTab.Title = 'Loss Estimation';
            app.AverageAnnualLossPanel = uipanel(app.LossEstimationTab);
            app.AverageAnnualLossPanel.Title = 'Average Annual Loss';
            app.AAL = uilabel(app.AverageAnnualLossPanel);
            app.AAL.Text = '$0';
            app.LossDeaggregationPanel = uipanel(app.LossEstimationTab);
            app.LossDeaggregationPanel.Title = 'Loss Deaggregation';
            app.DNELabel_4 = uilabel(app.LossDeaggregationPanel);
            app.DNELabel_4.Text = 'Collapse:';
            app.DNELabel_5 = uilabel(app.LossDeaggregationPanel);
            app.DNELabel_5.Text = 'Demo:';
            app.DNELabel_6 = uilabel(app.LossDeaggregationPanel);
            app.DNELabel_6.Text = 'Repair:';
            app.CollapseDeaggregation = uilabel(app.LossDeaggregationPanel);
            app.CollapseDeaggregation.Text = '$0 (0.0%)';
            app.DemoAggregation = uilabel(app.LossDeaggregationPanel);
            app.DemoAggregation.Text = '$0 (0.0%)';
            app.RepairAggregation = uilabel(app.LossDeaggregationPanel);
            app.RepairAggregation.Text = '$0 (0.0%)';
            app.PercentTotalCostLossPanel = uipanel(app.LossEstimationTab);
            app.PercentTotalCostLossPanel.Title = 'Percent Total Cost Loss';
            app.PercentCost = uilabel(app.PercentTotalCostLossPanel);
            app.PercentCost.Text = '0 %';
            app.UIAxes4 = uiaxes(app.LossEstimationTab);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes4.XLim = [0 1];
            app.UIAxes4.YLim = [0 1];
            app.UIAxes4.ZLim = [0 1];
            app.UIAxes4.CLim = [0 1];
            app.UIAxes4.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes4.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes4.ZTick = [0 0.5 1];
            app.LossPlotOptionDropDownLabel = uilabel(app.LossEstimationTab);
            app.LossPlotOptionDropDownLabel.Text = 'Loss Plot Option:';
            app.LossPlotOptionsDropDown = uidropdown(app.LossEstimationTab);
            app.LossPlotOptionsDropDown.Items = {'E[L | IM]', 'Loss Deaggregation', '% Loss Comparison'};
            app.LossPlotOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @LossPlotOptionsDropDownValueChanged, true);
            app.LossPlotOptionsDropDown.Value = 'E[L | IM]';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Final_App_Large

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end