classdef Final_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        Menu3                           matlab.ui.container.Menu
        Menu4                           matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        HazardInputPanel                matlab.ui.container.Panel
        OrderofFittingPolynomialSwitchLabel  matlab.ui.control.Label
        OrderofFittingPolynomialSwitch  matlab.ui.control.RockerSwitch
        FileUploadTextAreaLabel         matlab.ui.control.Label
        FileUploadTextArea              matlab.ui.control.TextArea
        LoadHazardCurveButton           matlab.ui.control.Button
        LowerHazardRange                matlab.ui.control.TextArea
        UpperHazardRange                matlab.ui.control.TextArea
        LowerIntegrationRange           matlab.ui.control.TextArea
        UpperIntegrationRange           matlab.ui.control.TextArea
        toLabel_2                       matlab.ui.control.Label
        toLabel_3                       matlab.ui.control.Label
        HazardFitRangeLabel             matlab.ui.control.Label
        SaIntegrationRangeLabel         matlab.ui.control.Label
        StructureInputPanel             matlab.ui.container.Panel
        LoadStructureDataButton         matlab.ui.control.Button
        FileUploadTextArea_2Label       matlab.ui.control.Label
        FileUploadTextArea_2            matlab.ui.control.TextArea
        LoadStructureResponseButton     matlab.ui.control.Button
        FileUploadTextArea_3Label       matlab.ui.control.Label
        FileUploadTextArea_3            matlab.ui.control.TextArea
        FragilitiesandLossPanel         matlab.ui.container.Panel
        FileUploadTextArea_4Label       matlab.ui.control.Label
        FileUploadTextArea_4            matlab.ui.control.TextArea
        LoadFragilityLossDataButton     matlab.ui.control.Button
        DisplayCheckEditFieldLabel      matlab.ui.control.Label
        DisplayCheckEditField           matlab.ui.control.NumericEditField
        DemolitionParametersPanel       matlab.ui.container.Panel
        MedianRIDRTextAreaLabel         matlab.ui.control.Label
        MedianRIDR                      matlab.ui.control.TextArea
        DispersionRIDRTextAreaLabel     matlab.ui.control.Label
        DispersionRIDR                  matlab.ui.control.TextArea
        RunAnalysisButton               matlab.ui.control.Button
        HazardTab                       matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        StructuralResponseTab           matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        EDPDropDownLabel                matlab.ui.control.Label
        EDPDropDown                     matlab.ui.control.DropDown
        CollapseFragilityTab            matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        DamageFragilitiesTab            matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        ComponentDropDownLabel          matlab.ui.control.Label
        ComponentDropDown               matlab.ui.control.DropDown
        LossEstimationTab               matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        Tab                             matlab.ui.container.Tab
        Tab_2                           matlab.ui.container.Tab
    end


    properties (Access = public)
        handles % Structure with many things in it
        hazardFile % Filename of the hazard curve
    end

    methods (Access = public)
    
        function getPolynomialOrder(app)
            order = app.OrderofFittingPolynomialSwitch.Value;
            if order == '3rd'
                app.handles.PolynomialOrder = 3;
            else
                app.handles.PolynomialOrder = 4;
            end
        end
        
        function plotPolyFit(app)
            app.UIAxes.NextPlot = 'replace';
            loglog(app.UIAxes2, app.handles.hazard(:,1), app.handles.hazard(:,2), 'o') 
            t=text(app.UIAxes2, app.handles.hazard(1,1), min(app.handles.hazardCurve(2,:)), app.handles.str1);
            t.FontSize = 9;
            t.BackgroundColor = 'w';
            t.EdgeColor = 'k';
            hold(app.UIAxes2)
            loglog(app.UIAxes2,app.handles.hazardCurve(1,:), app.handles.hazardCurve(2,:))
            title(app.UIAxes2,'Seismic Hazard Curve')
            xlabel(app.UIAxes2,'Sa (g)')
            ylabel(app.UIAxes2,'Mean Annual Frequency (1/yr)')
            legend(app.UIAxes2,'Hazard Curve','Polyfit')
            set(app.UIAxes2, 'XLim', [app.handles.hazard(1,1),app.handles.hazard(end,1)])
            %app.UIAxes2.Position = [42 34 450 393];
%             set(app.UIAxes2, ...
%               'Box'         , 'off'     , ...
%               'TickDir'     , 'out'     , ...
%               'TickLength'  , [.02 .02] , ...
%               'XMinorTick'  , 'on'      , ...
%               'YMinorTick'  , 'on');
%             app.UIAxes2.XLim = [app.handles.hazard(1,1),app.handles.hazard(end,1)];
            
            hold(app.UIAxes2)
            
        end
        
        function plotEDPresponse(app, value)
         
            plot(app.UIAxes5,app.handles.stripes,app.handles.EDPtype.(value).GMData, 'Color', [0.8 0.8 0.8])
            hold(app.UIAxes5, 'on');
            plot(app.UIAxes5,app.handles.stripes,app.handles.EDPtype.(value).meanEDP,'LineWidth',3,'Color','r')
            plot(app.UIAxes5,app.handles.stripes,app.handles.EDPtype.(value).medianEDP,'LineWidth',3,'Color','b')
            title(app.UIAxes5,[value, ' v. IM'])
            ylabel(app.UIAxes5,value)
            xlabel(app.UIAxes5,'Sa')
            set(app.UIAxes5, ...
              'Box'         , 'off'     , ...
              'TickDir'     , 'out'     , ...
              'TickLength'  , [.02 .02] , ...
              'XMinorTick'  , 'on'      , ...
              'YMinorTick'  , 'on');
            hold(app.UIAxes5,'off');
            
        end
        
        function plotFragilities(app, value)
            
            plot(app.UIAxes3, app.handles.(value).EDP, app.handles.(value).P_Damage, 'LineWidth', 2)
            legendary = strings(1, app.handles.(value).NumDS);
            for i = app.handles.(value).NumDS
            legendary(i) = strcat('DS', num2str(i));
            end
            title(app.UIAxes5, ([(value), ' Fragilities']))
            xlabel(app.UIAxes5, strcat('EDP (', app.handles.(value).EDPtype, ')'))
            ylabel(app.UIAxes5, 'P[Ds > ds | EDP]')
            legend(app.UIAxes5, legendary)
            set(app.UIAxes3, ...
              'Box'         , 'off'     , ...
              'TickDir'     , 'out'     , ...
              'TickLength'  , [.02 .02] , ...
              'XMinorTick'  , 'on'      , ...
              'YMinorTick'  , 'on');
            grid on
            set(app.UIAxes3, 'XLim', [app.handles.(value).EDP(1),app.handles.(value).EDP(end)])

        end
        
        function plotCollapse(n,app)
            plot(app.UIAxes,app.handles.stripes,app.handles.numberCollapse/n,'o',app.handles.hazardDerivative(1,:),app.handles.P_collapse,'k')
            grid on
            title(app.UIAxes,'Collapse Fragility Function')
            legend(app.UIAxes,'Stripe Analysis Median Collapse', 'Max Likelihood Fragility Fit')
            xlabel(app.UIAxes,'Sa (g)')
            ylabel(app.UIAxes,'P[C]')
            legend(app.UIAxes,'Location','northwest')
            xlim(app.UIAxes,[0,2])
            set(gca, ...
              'Box'         , 'off'     , ...
              'TickDir'     , 'out'     , ...
              'TickLength'  , [.02 .02] , ...
              'XMinorTick'  , 'on'      , ...
              'YMinorTick'  , 'on');
        end
        
        function plotLossDeaggregation(app)

            hold(app.UIAxes4)
            plot(app.UIAxes4, [0,app.handles.hazardCurve(1,:)], [0, app.handles.L_IM], 'k', 'LineWidth', 1.5)
            plot(app.UIAxes4, app.handles.hazardCurve(1,:), app.handles.ncLoss_IM, '--r')
            plot(app.UIAxes4, app.handles.hazardCurve(1,:), app.handles.collapseLoss_IM, '--g')
            plot(app.UIAxes4, app.handles.hazardCurve(1,:), app.handles.demoLoss_IM, '--b')
            xlabel(app.UIAxes4,'Sa (g)')
            ylabel(app.UIAxes4,'E[L|IM] ($)')
            title(app.UIAxes4,'Expected Loss Given IM')
            legend(app.UIAxes4,'Total Loss', 'No Collapse, Repair', 'Collapse', 'Demo')
            set(app.UIAxes4, ...
              'Box'         , 'off'     , ...
              'TickDir'     , 'out'     , ...
              'TickLength'  , [.02 .02] , ...
              'XMinorTick'  , 'on'      , ...
              'YMinorTick'  , 'on');
            grid on
            hold(app.UIAxes4)
            
        end
        
        function stripes = parseStripes(app, filenames)
            stripes = zeros(length(filenames), 1);
            for i = 1:length(filenames)
                str = filenames{i};
                split = strsplit(str, {'_Sa', 'Sa', 'Sa_', '_'});
                for j = 1:length(split)
                    check = str2double(split{j});
                    if isnan(check)
                        continue
                    else
                        stripes(i) = check;
                        break
                    end
                end
            end
        end
end


    methods (Access = private)

        % Button pushed function: LoadHazardCurveButton
        function LoadHazardCurve(app, event)
             app.hazardFile = uigetfile('*.*');
             app.UIFigure.Visible = 'off';
             app.UIFigure.Visible = 'on';
             if isequal(app.hazardFile, 0)
                 app.FileUploadTextArea.Value = 'User Selected Cancel';
             else
                 app.FileUploadTextArea.Value = app.hazardFile;
             end
        end

        % Value changed function: OrderofFittingPolynomialSwitch
        function PolynomialOrder(app, event)
            
            
        end

        % Button pushed function: LoadStructureDataButton
        function LoadStructure(app, event)
             app.handles.structureFile = uigetfile('*.*');
             app.UIFigure.Visible = 'off';
             app.UIFigure.Visible = 'on';
             if isequal(app.handles.structureFile, 0)
                 disp('User select Cancel')
             else
                 app.FileUploadTextArea_2.Value = app.handles.structureFile;
             end
        end

        % Button pushed function: LoadStructureResponseButton
        function LoadResponse(app, event)
             app.handles.responseFiles = uigetfile('*.csv', 'Select all Files', 'MultiSelect', 'on');
             app.UIFigure.Visible = 'off';
             app.UIFigure.Visible = 'on';
             if isequal(app.handles.responseFiles, 0)
                 app.FileUploadTextArea_3.Value = 'User Selection Cancelled';
             else
                 app.FileUploadTextArea_3.Value = app.handles.responseFiles;
             end
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysis(app, event)
            % Load in the hazard curve
            app.getPolynomialOrder();
            app.handles.hazard = load(app.hazardFile);
            l = str2double(app.LowerHazardRange.Value);
            u = str2double(app.UpperHazardRange.Value);
            app.handles.interval = [l, u];
            l = str2double(app.LowerIntegrationRange.Value);
            u = str2double(app.UpperIntegrationRange.Value);
            app.handles.SaIntegration = [l, u];
            
            [app]= createPolyFit(app);
            app.plotPolyFit();        
            
            % Initialize range of variables for integration
            app.handles.EDP.IDR = 0.0001:.0001:.15;
            app.handles.EDP.PFA = 0.001:.005:4.0;
            app.handles.EDP.RIDR = 0.0001:.0001:.1;
            
            % GUI Inputs
            
            ns = length(app.handles.responseFiles); %ns - number of stripes
            file = size(readtable(app.handles.responseFiles{1})); % Can we get this from loading file?
            n = file(2)-1; %n - number of GM
          
            
            filenames = app.handles.responseFiles;
            app.handles.stripes = parseStripes(app, filenames);
            app = LoadStripeData(app, filenames, ns);
            app = ResponseEstimation(app);
            
            app.EDPDropDown.Items = app.handles.EDPnames;
            
            app = CollapseFragility(n, app);
            plotCollapse(n, app)
            
            filename = app.handles.fragilitylossFile;
            app = loadComputeDamageFragilities(app, filename);
            app.ComponentDropDown.Items = app.handles.Components;
            
            filename = app.handles.structureFile;
            app = loadStructure(filename, app);
            
            % Return expected loss given EDP conditioned on no collapse and no repair
            app = expectedLoss_EDP(app);
            
            % Return expected loss given IM conditioned on no collapse and no repair
            app = expectedLoss_IM(app);
            
            % Probability of Demolition Given No Collapse
            app = ExpectedLoss(app);
            
            % Find probabilities of all 3 ingredients (repair, demo, collapse) and plot
            % all loss functions given IM
            app = repairCollapseDemoProb(app);
            plotLossDeaggregation(app)
            
            % Calculate the AAL and deaggregation ratios (could be used for fun things)
            app = averageAnnualLoss(app);
            
            
        end

        % Button pushed function: LoadFragilityLossDataButton
        function LoadFragilityLoss(app, event)
             app.handles.fragilitylossFile = uigetfile('*.*');
             app.UIFigure.Visible = 'off';
             app.UIFigure.Visible = 'on';
             if isequal(app.handles.fragilitylossFile, 0)
                 disp('User select Cancel')
             else
                 app.FileUploadTextArea_4.Value = app.handles.fragilitylossFile;
             end
        end

        % Value changed function: DisplayCheckEditField
        function DisplayCheck(app, event)
            app.DisplayCheckEditField.Value = app.handles.PolynomialOrder;
        end

        % Value changed function: EDPDropDown
        function EDPmedianResponse(app, event)
            value = app.EDPDropDown.Value;
            plotEDPresponse(app,value);
        end

        % Value changed function: ComponentDropDown
        function fragilityPlot(app, event)
            value = app.ComponentDropDown.Value;
            plotFragilities(app,value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.QuitMenu = uimenu(app.Menu);
            app.QuitMenu.Text = 'Quit';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.Text = 'Save';
            app.Menu3 = uimenu(app.Menu);
            app.Menu3.Text = 'Menu3';
            app.Menu4 = uimenu(app.Menu);
            app.Menu4.Text = 'Menu4';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.HazardInputPanel = uipanel(app.InputsTab);
            app.HazardInputPanel.Title = 'Hazard Input';
            app.OrderofFittingPolynomialSwitchLabel = uilabel(app.HazardInputPanel);
            app.OrderofFittingPolynomialSwitchLabel.Text = 'Order of Fitting Polynomial';
            app.OrderofFittingPolynomialSwitch = uiswitch(app.HazardInputPanel, 'rocker');
            app.OrderofFittingPolynomialSwitch.Items = {'3rd', '4th'};
            app.OrderofFittingPolynomialSwitch.ValueChangedFcn = createCallbackFcn(app, @PolynomialOrder, true);
            app.OrderofFittingPolynomialSwitch.Value = '3rd';
            app.FileUploadTextAreaLabel = uilabel(app.HazardInputPanel);
            app.FileUploadTextAreaLabel.Text = 'File Upload:';
            app.FileUploadTextArea = uitextarea(app.HazardInputPanel);
            app.FileUploadTextArea.Editable = 'off';
            app.FileUploadTextArea.Value = {'Please Load Hazard'};
            app.LoadHazardCurveButton = uibutton(app.HazardInputPanel, 'push');
            app.LoadHazardCurveButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHazardCurve, true);
            app.LoadHazardCurveButton.Text = 'Load Hazard Curve';
            app.LowerHazardRange = uitextarea(app.HazardInputPanel);
            app.LowerHazardRange.Value = {'0.01'};
            app.UpperHazardRange = uitextarea(app.HazardInputPanel);
            app.UpperHazardRange.Value = {'2'};
            app.LowerIntegrationRange = uitextarea(app.HazardInputPanel);
            app.LowerIntegrationRange.Value = {'0.01'};
            app.UpperIntegrationRange = uitextarea(app.HazardInputPanel);
            app.UpperIntegrationRange.Value = {'3'};
            app.toLabel_2 = uilabel(app.HazardInputPanel);
            app.toLabel_2.Text = 'to';
            app.toLabel_3 = uilabel(app.HazardInputPanel);
            app.toLabel_3.Text = 'to';
            app.HazardFitRangeLabel = uilabel(app.HazardInputPanel);
            app.HazardFitRangeLabel.Text = 'Hazard Fit Range:';
            app.SaIntegrationRangeLabel = uilabel(app.HazardInputPanel);
            app.SaIntegrationRangeLabel.Text = 'Sa Integration Range:';
            app.StructureInputPanel = uipanel(app.InputsTab);
            app.StructureInputPanel.Title = 'Structure Input';
            app.LoadStructureDataButton = uibutton(app.StructureInputPanel, 'push');
            app.LoadStructureDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStructure, true);
            app.LoadStructureDataButton.Text = 'Load Structure Data';
            app.FileUploadTextArea_2Label = uilabel(app.StructureInputPanel);
            app.FileUploadTextArea_2Label.Text = 'File Upload:';
            app.FileUploadTextArea_2 = uitextarea(app.StructureInputPanel);
            app.FileUploadTextArea_2.Value = {'Please Load Structure'};
            app.LoadStructureResponseButton = uibutton(app.StructureInputPanel, 'push');
            app.LoadStructureResponseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResponse, true);
            app.LoadStructureResponseButton.Text = 'Load Structure Response';
            app.FileUploadTextArea_3Label = uilabel(app.StructureInputPanel);
            app.FileUploadTextArea_3Label.Text = 'File Upload:';
            app.FileUploadTextArea_3 = uitextarea(app.StructureInputPanel);
            app.FileUploadTextArea_3.Value = {'Please Load Response'};
            app.FragilitiesandLossPanel = uipanel(app.InputsTab);
            app.FragilitiesandLossPanel.Title = 'Fragilities and Loss';
            app.FileUploadTextArea_4Label = uilabel(app.FragilitiesandLossPanel);
            app.FileUploadTextArea_4Label.Text = 'File Upload:';
            app.FileUploadTextArea_4 = uitextarea(app.FragilitiesandLossPanel);
            app.FileUploadTextArea_4.Editable = 'off';
            app.FileUploadTextArea_4.Value = {'Please Load Fragilities'};
            app.LoadFragilityLossDataButton = uibutton(app.FragilitiesandLossPanel, 'push');
            app.LoadFragilityLossDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFragilityLoss, true);
            app.LoadFragilityLossDataButton.Text = 'Load Fragility/Loss Data';
            app.DisplayCheckEditFieldLabel = uilabel(app.FragilitiesandLossPanel);
            app.DisplayCheckEditFieldLabel.Text = 'Display Check';
            app.DisplayCheckEditField = uieditfield(app.FragilitiesandLossPanel, 'numeric');
            app.DisplayCheckEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayCheck, true);
            app.DisplayCheckEditField.Editable = 'off';
            app.DemolitionParametersPanel = uipanel(app.InputsTab);
            app.DemolitionParametersPanel.Title = 'Demolition Parameters:';
            app.MedianRIDRTextAreaLabel = uilabel(app.DemolitionParametersPanel);
            app.MedianRIDRTextAreaLabel.Text = 'Median RIDR:';
            app.MedianRIDR = uitextarea(app.DemolitionParametersPanel);
            app.MedianRIDR.Value = {'0.015'};
            app.DispersionRIDRTextAreaLabel = uilabel(app.DemolitionParametersPanel);
            app.DispersionRIDRTextAreaLabel.Text = 'Dispersion RIDR:';
            app.DispersionRIDR = uitextarea(app.DemolitionParametersPanel);
            app.DispersionRIDR.Value = {'0.3'};
            app.RunAnalysisButton = uibutton(app.InputsTab, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysis, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.HazardTab = uitab(app.TabGroup);
            app.HazardTab.Title = 'Hazard';
            app.UIAxes2 = uiaxes(app.HazardTab);
            title(app.UIAxes2, 'Seismic Hazard Curve')
            xlabel(app.UIAxes2, 'IM')
            ylabel(app.UIAxes2, 'Annual Rate')
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.YLim = [0 1];
            app.UIAxes2.ZLim = [0 1];
            app.UIAxes2.CLim = [0 1];
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2.ZTick = [0 0.5 1];
            app.StructuralResponseTab = uitab(app.TabGroup);
            app.StructuralResponseTab.Title = 'Structural Response';
            app.UIAxes5 = uiaxes(app.StructuralResponseTab);
            title(app.UIAxes5, 'EDP v. IM')
            xlabel(app.UIAxes5, 'IM')
            ylabel(app.UIAxes5, 'EDP')
            app.EDPDropDownLabel = uilabel(app.StructuralResponseTab);
            app.EDPDropDownLabel.Text = 'EDP';
            app.EDPDropDown = uidropdown(app.StructuralResponseTab);
            app.EDPDropDown.ValueChangedFcn = createCallbackFcn(app, @EDPmedianResponse, true);
            app.CollapseFragilityTab = uitab(app.TabGroup);
            app.CollapseFragilityTab.Title = 'Collapse Fragility';
            app.UIAxes = uiaxes(app.CollapseFragilityTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.DamageFragilitiesTab = uitab(app.TabGroup);
            app.DamageFragilitiesTab.Title = 'Damage Fragilities';
            app.UIAxes3 = uiaxes(app.DamageFragilitiesTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3.XLim = [0 1];
            app.UIAxes3.YLim = [0 1];
            app.UIAxes3.ZLim = [0 1];
            app.UIAxes3.CLim = [0 1];
            app.UIAxes3.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes3.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes3.ZTick = [0 0.5 1];
            app.ComponentDropDownLabel = uilabel(app.DamageFragilitiesTab);
            app.ComponentDropDownLabel.Text = 'Component';
            app.ComponentDropDown = uidropdown(app.DamageFragilitiesTab);
            app.ComponentDropDown.ValueChangedFcn = createCallbackFcn(app, @fragilityPlot, true);
            app.LossEstimationTab = uitab(app.TabGroup);
            app.LossEstimationTab.Title = 'Loss Estimation';
            app.UIAxes4 = uiaxes(app.LossEstimationTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes4.XLim = [0 1];
            app.UIAxes4.YLim = [0 1];
            app.UIAxes4.ZLim = [0 1];
            app.UIAxes4.CLim = [0 1];
            app.UIAxes4.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes4.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes4.ZTick = [0 0.5 1];
            app.Tab = uitab(app.TabGroup);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Tab';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Final_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end